classdef user < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout4             matlab.ui.container.GridLayout
        RPButton                matlab.ui.control.Button
        EyePanel                matlab.ui.container.Panel
        showinfoButton          matlab.ui.control.Button
        setupButton             matlab.ui.control.Button
        InformationPanel        matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        Label                   matlab.ui.control.Label
        MediumListPanel         matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        UITable                 matlab.ui.control.Table
        addmediumDropDown       matlab.ui.control.DropDown
        addmediumDropDownLabel  matlab.ui.control.Label
        CreateButton            matlab.ui.control.Button
    end

    %% 額外屬性 (Private)
    properties (Access = private)
        DialogApp_Create % Dialog box app 
        DialogApp_Modify
        DialogApp_SetupEye
    end
    
    %% 額外屬性 (Public) (For store data)
    properties (Access = public)
        E % Eye
        M % MediumList
        % empty table
        % column: "Name", "Type", "Order", "up", "down", "Modify", "Delete"
        T = cell2table(cell(0,7), 'VariableNames', {'Name','Type',...
            'Order','up','down','Modify','Delete'})
    end
    
    %% public method
    methods (Access = public)

        %% 從 dialog 呼叫此方法
        function update_from_eye(app,object_M,object_E)
            % 
            app.E = object_E;
            disp("[info]: 已設定 Eye.")

            % 檢查目前是否有 tag 為 "eye" 的介質
            check = app.M.list(arrayfun(@(x) isequal(x.tag,"eye"),app.M.list));
            if isempty(check)       % 新增含眼睛介質
                M_new = MediumList;
                M_new.add(object_M)
                for ii = 1:length(app.M.list)
                    M_new.add(app.M.list(ii))
                end
                app.M = M_new;
                app.M.update_order     
                app.update_T        
                app.update_table    
                disp("[info]: 已創建一個名為 " + object_M.name + " 的 " + object_M.type + " (含眼睛的介質)")
            elseif ~isempty(check)  % Modify 含眼睛介質
                app.M.replace(check.order,object_M)     
                app.M.update_order 
                app.update_T                    
                app.update_table                
                disp("[info]: 已更新一個名為 " + object_M.name + " 的 " + object_M.type + " (含眼睛的介質)")
            end
        end

        %%
        function update_from_create(app,object)
            disp("[info]: 已創建一個名為 " + object.name + " 的 " + object.type)
            app.M.add(object)   % 新增 Medium
            app.M.update_order     % 自動填入順序 (根據建立順序)
            app.update_T        % 更新 T 屬性
            app.update_table    % 更新顯示的 table
        end

        %% 
        function update_from_modify(app,index,object)
            disp("[info]: 已修改一個名為 " + object.name + " 的 " + object.type)
            app.M.replace(index,object)     % 更新 Medium
            app.M.update_order 
            app.update_T                    % 更新 T 屬性
            app.update_table                % 更新顯示的 table
        end

        
    end
    
    %% private method
    methods (Access = private)
        %% update Table
        function update_T(app)
            num = length(app.M.list);
            name = cell(num,1);
            type = cell(num,1);
            order = cell(num,1);
            up = cell(num,1); down = cell(num,1); modify = cell(num,1); delete = cell(num,1);
            for ii = 1 : num
                name{ii} = char(app.M.list(ii).name);
                type{ii} = char(app.M.list(ii).type);
                order{ii} = num2str(app.M.list(ii).order);
            end
            app.T = table(name,type,order,up,down,modify,delete);
        end
        
        %% 更新 medium list table
        function update_table(app)
            app.UITable.Data = table2cell(app.T);
        end

        %% 更新 info panel (by 選擇 table 不同 cell)
        function update_info(app,str)
            app.Label.Text = str;
        end

        %% check medium list 
        function check_M(app)
            % 檢查是否有眼睛
            if isempty(app.E.VD)
                warndlg("無法計算 RP。尚未設定眼睛")
                return
            end
            
            % 檢查 Lens (要一凹一凸)
            target = app.M.find(type="Lens");
            try
                for ii = 1:length(target)
                    now = target(ii);
                    switch now.reversed
                        case 0
                            last = target(ii-1);
                            switch now.vex_cave
                                case 1  % 該層為凸透鏡朝上 --> 檢查上一層為凹透鏡朝下
                                    if last.reversed == 1 && last.vex_cave == -1
                                        % pass
                                    else
                                        error("")
                                    end
                                case -1 % 該層為凹透鏡朝上 --> 檢查上一層為凸透鏡朝下
                                    if last.reversed == 1 && last.vex_cave == 1
                                        % pass
                                    else
                                        error("")
                                    end
                            end
                        case 1
                            next = target(ii+1);
                            switch now.vex_cave
                                case 1  % 該層為凸透鏡朝下 --> 檢查下一層為凹透鏡朝上
                                    if next.reversed == 0 && next.vex_cave == -1
                                        % pass
                                    else
                                        error("")
                                    end
                                case -1 % 該層為凹透鏡朝下 --> 檢查下一層為凸透鏡朝上
                                    if next.reversed == 0 && next.vex_cave == 1
                                        % pass
                                    else
                                        error("")
                                    end
                            end
                    end
                end
            catch
                warndlg(["Lens 層檢測出問題。";"請確保 Lens 層有一凹一凸 2 層包覆成 Cube"])
                return
            end
            disp("[info]: Medium list 未檢測出問題，即將開始計算 RP")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add pathtool
            addpath(fullfile(cd,"window"));
            addpath(fullfile(cd,"function"));
            addpath(fullfile(cd,"class"));

            % E, M
            app.E = Eye;
            app.M = MediumList;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            enable(app,"off")
    
            % Call dialog box with input values
            switch app.addmediumDropDown.Value
                case "Cube"
                    disp("[info]: 創建 Cube...")
                    app.DialogApp_Create = create_cube(app);
                case "Lens"
                    disp("[info]: 創建 Lens...")
                    app.DialogApp_Create = create_lens(app);
                case "GP"
                    disp("[info]: 創建 GP...")
                    app.DialogApp_Create = create_gp(app);
            end
            
        end

        % Button pushed function: setupButton
        function setupButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            enable(app,"off")
            
            disp("[info]: 設定 Eye...")

            % Call dialog box with input values
            app.DialogApp_SetupEye = setup_eye(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           % Delete all apps
           delete(app.DialogApp_Create)
           delete(app.DialogApp_Modify)
           delete(app.DialogApp_SetupEye)
           delete(app)    
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            %% empty or multiselect --> return
            if size(indices,1) ~= 1
                return
            end

            %% selected column name
            try
                column_name = app.UITable.ColumnName{indices(2)};
            catch
                column_name = '';
            end

            %% select medium with eye: up, down, modify, delete --> no response
            if isequal(app.M.list(indices(1)).tag,"eye")
                if isequal(column_name,'up') ||...
                    isequal(column_name,'down') ||...
                    isequal(column_name,'Modify') ||...
                    isequal(column_name,'Delete')
                    warndlg("[warning]: 無法直接在 medium list 操作 medium with eye 介質")
                    app.UITable.Selection = [];
                    return
                end
            end

            %% show information: name type order
            if isequal(column_name,'Name') || ...
                    isequal(column_name,'Type') || ...
                    isequal(column_name,'Order')
                disp("[info]: Show information of medium @ row "+ indices(1))
                str = info_extract(app.M.list(indices(1))); % useful property extract
                update_info(app, str) % show in info panel
                % 取消選取
                app.UITable.Selection = [];
            else    % info panel white out
                update_info(app,"")
            end

            %% order setup
            switch column_name
                case 'up'  % 該 row 往上移一格順序
                    if indices(1) ~= 1
                        temp = app.M.list(indices(1)-1);
                        if isequal(temp.tag,"eye")
                            warndlg("[warning]: 無法與 medium with eye 介質交換順序")
                            app.UITable.Selection = [];
                            return
                        end
                        app.M.list(indices(1)-1) = app.M.list(indices(1));
                        app.M.list(indices(1)) = temp;
                        app.M.update_order
                        app.update_T        % 更新 T 屬性
                        app.update_table    % 更新顯示的 table
                        disp("[info]: 將 medium row "+ string(indices(1))+" 移至 row "+ string(indices(1)-1));
                    end
                    % 取消選取 (可以在同一格再點選一次)
                    app.UITable.Selection = [];
                case 'down'  % 該 row 往下移一格順序
                    total_number = length(app.M.list);
                    if indices(1) ~= total_number
                        temp = app.M.list(indices(1)+1);
                        app.M.list(indices(1)+1) = app.M.list(indices(1));
                        app.M.list(indices(1)) = temp;
                        app.M.update_order
                        app.update_T        % 更新 T 屬性
                        app.update_table    % 更新顯示的 table
                        disp("[info]: 將 medium row "+ string(indices(1))+" 移至 row "+ string(indices(1)-1));
                    end
                    % 取消選取 (可以在同一格再點選一次)
                    app.UITable.Selection = [];
            end
  

            %% modification
            if isequal(column_name,'Modify')
                index = indices(1);
                % Disable Plot Options button while dialog is open
                enable(app,"off")
        
                % Call dialog box with input values
                disp("[info]: 修改名為 " + app.M.list(index).name + " 的 " +...
                    app.M.list(index).type + " ...");
                switch app.M.list(index).type
                    case "Cube"
                        app.DialogApp_Modify = modify_cube(app,index);
                    case "GP"
                        app.DialogApp_Modify = modify_gp(app,index);
                    case "Lens"
                        app.DialogApp_Modify = modify_lens(app,index);
                end

                % 取消選取
                app.UITable.Selection = [];
            end


            %% delete
            if isequal(column_name,'Delete')
                enable(app,"off")

                % ask
                sec = "(名為 " + app.M.list(indices(1)).name + " 的 " +app.M.list(indices(1)).type + ")";
                anwser = questdlg(["確定要刪除此 Medium?";sec],"delete","Yes","No","No");
                switch anwser
                    case "Yes"
                        app.M.delete(indices(1))
                        app.M.update_order
                        app.update_T                    % 更新 T 屬性
                        app.update_table                % 更新顯示的 table
                    case "No"
                end
                enable(app,"on")

                % 取消選取 (可以在同一格再點選一次)
                app.UITable.Selection = [];
            end
        end

        % Button pushed function: showinfoButton
        function showinfoButtonPushed(app, event)
            disp("[info]: Show information of eye")
            str = info_extract(app.E); % useful property extract
            update_info(app, str) % show in info panel
        end

        % Button pushed function: RPButton
        function RPButtonPushed(app, event)
            % 檢查輸入有效性
            app.check_M
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout4 = uigridlayout(app.UIFigure);
            app.MediumListPanel = uipanel(app.GridLayout4);
            app.MediumListPanel.Title = 'Medium List';
            app.GridLayout2 = uigridlayout(app.MediumListPanel);
            app.CreateButton = uibutton(app.GridLayout2, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.addmediumDropDownLabel = uilabel(app.GridLayout2);
            app.addmediumDropDownLabel.Text = 'add medium';
            app.addmediumDropDown = uidropdown(app.GridLayout2);
            app.addmediumDropDown.Items = {'Cube', 'Lens', 'GP'};
            app.addmediumDropDown.Value = 'Cube';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Name'; 'Type'; 'Order'; 'up'; 'down'; 'Modify'; 'Delete'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.InformationPanel = uipanel(app.GridLayout4);
            app.InformationPanel.Title = 'Information';
            app.GridLayout3 = uigridlayout(app.InformationPanel);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Interpreter = 'html';
            app.Label.Text = '';
            app.EyePanel = uipanel(app.GridLayout4);
            app.EyePanel.Title = 'Eye';
            app.setupButton = uibutton(app.EyePanel, 'push');
            app.setupButton.ButtonPushedFcn = createCallbackFcn(app, @setupButtonPushed, true);
            app.setupButton.Text = 'setup';
            app.showinfoButton = uibutton(app.EyePanel, 'push');
            app.showinfoButton.ButtonPushedFcn = createCallbackFcn(app, @showinfoButtonPushed, true);
            app.showinfoButton.Text = 'show info ';
            app.RPButton = uibutton(app.GridLayout4, 'push');
            app.RPButton.ButtonPushedFcn = createCallbackFcn(app, @RPButtonPushed, true);
            app.RPButton.Text = 'RP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end