classdef create_cube < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        refractiveindexEditField       matlab.ui.control.NumericEditField
        refractiveindexEditFieldLabel  matlab.ui.control.Label
        thicknessEditField             matlab.ui.control.NumericEditField
        thicknessEditFieldLabel        matlab.ui.control.Label
        nameEditField                  matlab.ui.control.EditField
        nameEditFieldLabel             matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app % 主程式呼叫此程式
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            % Store main app object (caller)
            app.CallingApp = caller;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % record data
            created_cube = Cube(thickness=app.thicknessEditField.Value,...
                refractive_index=app.refractiveindexEditField.Value);
            created_cube.set_name(app.nameEditField.Value);
            
            % Call main app's public function
            update_from_create(app.CallingApp,created_cube);
            
            % Enable Create button in main app
            app.CallingApp.CreateButton.Enable = "on";
            app.CallingApp.UITable.Enable = "on";
            
            % Delete the dialog box  
            delete(app) 
        end

        % Value changed function: nameEditField
        function nameEditFieldValueChanged(app, event)
            value = app.nameEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           disp("[info]: 取消")
            
           % Enable Create button in main app
           app.CallingApp.CreateButton.Enable = "on";
           app.CallingApp.UITable.Enable = "on";
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
           disp("[info]: 取消")
            
           % Enable Create button in main app
           app.CallingApp.CreateButton.Enable = "on";
           app.CallingApp.UITable.Enable = "on";
                    
           % Delete the dialog box 
           delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CreateButton = uibutton(app.GridLayout, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.nameEditFieldLabel = uilabel(app.GridLayout);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.GridLayout, 'text');
            app.nameEditField.ValueChangedFcn = createCallbackFcn(app, @nameEditFieldValueChanged, true);
            app.nameEditField.Placeholder = '(cube)';
            app.thicknessEditFieldLabel = uilabel(app.GridLayout);
            app.thicknessEditFieldLabel.Text = 'thickness';
            app.thicknessEditField = uieditfield(app.GridLayout, 'numeric');
            app.thicknessEditField.Limits = [0 Inf];
            app.refractiveindexEditFieldLabel = uilabel(app.GridLayout);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.GridLayout, 'numeric');
            app.refractiveindexEditField.Limits = [0 Inf];
            app.refractiveindexEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_cube(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end