classdef create_gp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        reversedLabel                  matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        manualGPPanel                  matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        size_verEditField              matlab.ui.control.NumericEditField
        size_verEditFieldLabel         matlab.ui.control.Label
        size_horEditField              matlab.ui.control.NumericEditField
        size_horEditFieldLabel         matlab.ui.control.Label
        PBAEditField_2                 matlab.ui.control.NumericEditField
        PBAEditField_2Label            matlab.ui.control.Label
        autoGPPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        WDGPEditField                  matlab.ui.control.NumericEditField
        WDGPEditFieldLabel             matlab.ui.control.Label
        PBAEditField                   matlab.ui.control.NumericEditField
        PBAEditFieldLabel              matlab.ui.control.Label
        listEditField                  matlab.ui.control.EditField
        listEditFieldLabel             matlab.ui.control.Label
        GPModeDropDown                 matlab.ui.control.DropDown
        GPModeDropDownLabel            matlab.ui.control.Label
        refractiveindexEditField       matlab.ui.control.NumericEditField
        refractiveindexEditFieldLabel  matlab.ui.control.Label
        thicknessEditField             matlab.ui.control.NumericEditField
        thicknessEditFieldLabel        matlab.ui.control.Label
        nameEditField                  matlab.ui.control.EditField
        nameEditFieldLabel             matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app % 主程式呼叫此程式
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            % Store main app object (caller)
            app.CallingApp = caller;
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % record data
            created_cube = Cube(thickness=app.thicknessEditField.Value,...
                refractive_index=app.refractiveindexEditField.Value);
            created_cube.set_name(app.nameEditField.Value);
            
            % Call main app's public function
            update_from_create(app.CallingApp,created_cube);
            
            % Enable Create button in main app
            app.CallingApp.CreateButton.Enable = "on";
            
            % Delete the dialog box  
            delete(app) 
        end

        % Value changed function: nameEditField
        function nameEditFieldValueChanged(app, event)
            value = app.nameEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           % Enable Create button in main app
           app.CallingApp.CreateButton.Enable = "on";
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
           disp("[info]: 取消")
           
           % Enable Create button in main app
           app.CallingApp.CreateButton.Enable = "on";
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Value changed function: GPModeDropDown
        function GPModeDropDownValueChanged(app, event)
            value = app.GPModeDropDown.Value;
            
            switch value
                case "noGP"
                    app.autoGPPanel.Visible = "off";
                    app.autoGPPanel.Enable = "off";
                    app.manualGPPanel.Visible = "off";
                    app.manualGPPanel.Enable = "off";
                case "AutoGP"
                    app.autoGPPanel.Visible = "on";
                    app.autoGPPanel.Enable = "on";
                    app.manualGPPanel.Visible = "off";
                    app.manualGPPanel.Enable = "off";
                case "ManualGP"
                    app.autoGPPanel.Visible = "off";
                    app.autoGPPanel.Enable = "off";
                    app.manualGPPanel.Visible = "on";
                    app.manualGPPanel.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CreateButton = uibutton(app.GridLayout, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.nameEditFieldLabel = uilabel(app.GridLayout);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.GridLayout, 'text');
            app.nameEditField.ValueChangedFcn = createCallbackFcn(app, @nameEditFieldValueChanged, true);
            app.nameEditField.Placeholder = '(gp)';
            app.thicknessEditFieldLabel = uilabel(app.GridLayout);
            app.thicknessEditFieldLabel.Text = 'thickness';
            app.thicknessEditField = uieditfield(app.GridLayout, 'numeric');
            app.thicknessEditField.Limits = [0 Inf];
            app.thicknessEditField.Tooltip = {'gp substrate + 0.5 * gp structure'};
            app.refractiveindexEditFieldLabel = uilabel(app.GridLayout);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.GridLayout, 'numeric');
            app.refractiveindexEditField.Limits = [0 Inf];
            app.refractiveindexEditField.Value = 1;
            app.GPModeDropDownLabel = uilabel(app.GridLayout);
            app.GPModeDropDownLabel.Text = 'GP Mode';
            app.GPModeDropDown = uidropdown(app.GridLayout);
            app.GPModeDropDown.Items = {'noGP', 'AutoGP', 'ManualGP'};
            app.GPModeDropDown.ValueChangedFcn = createCallbackFcn(app, @GPModeDropDownValueChanged, true);
            app.GPModeDropDown.Value = 'noGP';
            app.autoGPPanel = uipanel(app.GridLayout);
            app.autoGPPanel.Enable = 'off';
            app.autoGPPanel.Title = 'autoGP';
            app.autoGPPanel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.autoGPPanel);
            app.listEditFieldLabel = uilabel(app.GridLayout2);
            app.listEditFieldLabel.Text = 'list';
            app.listEditField = uieditfield(app.GridLayout2, 'text');
            app.listEditField.Value = '{Cube(),[],Cube()}';
            app.PBAEditFieldLabel = uilabel(app.GridLayout2);
            app.PBAEditFieldLabel.Text = ' PBA';
            app.PBAEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PBAEditField.Value = 41.5;
            app.WDGPEditFieldLabel = uilabel(app.GridLayout2);
            app.WDGPEditFieldLabel.Text = 'WDGP';
            app.WDGPEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WDGPEditField.Value = 550;
            app.manualGPPanel = uipanel(app.GridLayout);
            app.manualGPPanel.Enable = 'off';
            app.manualGPPanel.Title = 'manualGP';
            app.manualGPPanel.Visible = 'off';
            app.GridLayout2_2 = uigridlayout(app.manualGPPanel);
            app.PBAEditField_2Label = uilabel(app.GridLayout2_2);
            app.PBAEditField_2Label.Text = ' PBA';
            app.PBAEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.size_horEditFieldLabel = uilabel(app.GridLayout2_2);
            app.size_horEditFieldLabel.Text = 'size_hor';
            app.size_horEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.size_verEditFieldLabel = uilabel(app.GridLayout2_2);
            app.size_verEditFieldLabel.Text = 'size_ver';
            app.size_verEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.reversedLabel = uilabel(app.GridLayout);
            app.reversedLabel.Tooltip = {'結構面是否為朝下'};
            app.reversedLabel.Text = 'reversed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_gp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end