classdef create_lens < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout3                    matlab.ui.container.GridLayout
        lenstypeDropDown               matlab.ui.control.DropDown
        lenstypeDropDownLabel          matlab.ui.control.Label
        apertureEditField              matlab.ui.control.NumericEditField
        apertureEditFieldLabel         matlab.ui.control.Label
        sizeverEditField               matlab.ui.control.NumericEditField
        sizeverEditFieldLabel          matlab.ui.control.Label
        sizehorEditField               matlab.ui.control.NumericEditField
        sizehorEditFieldLabel          matlab.ui.control.Label
        pitchEditField                 matlab.ui.control.NumericEditField
        pitchLabel                     matlab.ui.control.Label
        radiusEditField                matlab.ui.control.NumericEditField
        radiusEditFieldLabel           matlab.ui.control.Label
        segmentEditField               matlab.ui.control.NumericEditField
        segmentEditFieldLabel          matlab.ui.control.Label
        LRAEditField                   matlab.ui.control.NumericEditField
        LRAEditFieldLabel              matlab.ui.control.Label
        refractiveindexEditField       matlab.ui.control.NumericEditField
        refractiveindexEditFieldLabel  matlab.ui.control.Label
        thicknessEI0EditField          matlab.ui.control.NumericEditField
        thicknessEI0EditFieldLabel     matlab.ui.control.Label
        nameEditField                  matlab.ui.control.EditField
        nameEditFieldLabel             matlab.ui.control.Label
        CheckBox_3                     matlab.ui.control.CheckBox
        AUFLabel                       matlab.ui.control.Label
        CheckBox_2                     matlab.ui.control.CheckBox
        GRLLabel                       matlab.ui.control.Label
        GRLPanel                       matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        importButton_2                 matlab.ui.control.Button
        reversedLabel                  matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        AUFPanel                       matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        endEditField                   matlab.ui.control.NumericEditField
        endEditFieldLabel              matlab.ui.control.Label
        numberEditField                matlab.ui.control.NumericEditField
        numberEditFieldLabel           matlab.ui.control.Label
        startEditField                 matlab.ui.control.NumericEditField
        startEditFieldLabel            matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app % 主程式呼叫此程式
        grl_path = "";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            % Store main app object (caller)
            app.CallingApp = caller;

            % 紀錄上一次的路徑 (TDL)
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % auf
            switch app.CheckBox_3.Value
                case 0
                    auf = AUF(AUFMode=0);
                case 1
                    try
                        auf = AUF(AUFMode=1,...
                                a_start=app.startEditField.Value,...
                                a_end=app.endEditField.Value,...
                                a_num=app.numberEditField.Value);
                    catch e
                        % 建立路徑資訊
                        temp_path = strings(length(e.stack),1);
                        for ii = 1:length(e.stack)
                            temp_path(ii) = "at: line("+ num2str(e.stack(ii).line) + ") " + e.stack(ii).name;
                        end
                        errordlg(["創建 AUF 失敗";...
                                  "identifier: " + e.identifier;...
                                  "message: " + e.message;...
                                  temp_path])
                        return % 離開此 function (create button function)
                    end
            end
            % grl
            switch app.CheckBox_2.Value
                case 0
                    grl = GRL(GRLMode=0);
                case 1
                    try
                        grl = GRL(GRLMode=1,file=app.grl_path);
                    catch e
                        % 建立路徑資訊
                        temp_path = strings(length(e.stack),1);
                        for ii = 1:length(e.stack)
                            temp_path(ii) = "at: line("+ num2str(e.stack(ii).line) + ") " + e.stack(ii).name;
                        end
                        errordlg(["創建 GRL 失敗";...
                                  "identifier: " + e.identifier;...
                                  "message: " + e.message;...
                                  temp_path])
                        return % 離開此 function (create button function)
                    end
            end
            % seg
            segment = Segment(num=app.segmentEditField.Value);
            % concave, convex
            switch app.lenstypeDropDown.Value
                case "convex"
                    vex_cave = 1;
                case "concave"
                    vex_cave = -1;
            end

            % lens
            lens = Lens(LRA = app.LRAEditField.Value,...
                        size_hor = app.sizehorEditField.Value,...
                        size_ver = app.sizeverEditField.Value,...
                        pitch = app.pitchEditField.Value,...
                        refractive_index = app.refractiveindexEditField.Value,...
                        thickness_EI0 = app.thicknessEI0EditField.Value,...
                        aperture = app.apertureEditField.Value,...
                        radius = app.radiusEditField.Value,...
                        reversed = app.CheckBox.Value,...
                        grl = grl, ...
                        auf = auf,...
                        segment = segment,...
                        vex_cave = vex_cave);
            lens.set_name(app.nameEditField.Value);
            
            % Call main app's public function
            update_from_create(app.CallingApp,lens);
            
            % Enable Create button in main app
            enable(app.CallingApp,"on")
            
            % Delete the dialog box  
            delete(app) 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           disp("[info]: 取消")
            
           % Enable Create button in main app
           enable(app.CallingApp,"on")
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
           disp("[info]: 取消")
           
           % Enable Create button in main app
           enable(app.CallingApp,"on")
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            switch value
                case 0
                    app.GRLPanel.Visible = "off";
                case 1
                    app.GRLPanel.Visible = "on";
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            switch value
                case 0
                    app.AUFPanel.Visible = "off";
                case 1
                    app.AUFPanel.Visible = "on";
            end
        end

        % Button pushed function: importButton_2
        function importButton_2Pushed(app, event)
            % search path for GRL
            [file,path] = uigetfile('*.mat',"MultiSelect","off");
            app.grl_path = fullfile(path,file);
            app.TextArea.Value = app.grl_path;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.CreateButton = uibutton(app.GridLayout3, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.GridLayout3, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AUFPanel = uipanel(app.GridLayout3);
            app.AUFPanel.Title = 'AUF';
            app.AUFPanel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.AUFPanel);
            app.startEditFieldLabel = uilabel(app.GridLayout2);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.GridLayout2, 'numeric');
            app.startEditField.Value = 0.1;
            app.numberEditFieldLabel = uilabel(app.GridLayout2);
            app.numberEditFieldLabel.Text = 'number';
            app.numberEditField = uieditfield(app.GridLayout2, 'numeric');
            app.numberEditField.RoundFractionalValues = 'on';
            app.numberEditField.Value = 10;
            app.endEditFieldLabel = uilabel(app.GridLayout2);
            app.endEditFieldLabel.Text = 'end';
            app.endEditField = uieditfield(app.GridLayout2, 'numeric');
            app.endEditField.Value = 1.001;
            app.CheckBox = uicheckbox(app.GridLayout3);
            app.CheckBox.Tag = 'reversed';
            app.CheckBox.Text = '';
            app.reversedLabel = uilabel(app.GridLayout3);
            app.reversedLabel.Tooltip = {'結構面是否為朝下'};
            app.reversedLabel.Text = 'reversed';
            app.GRLPanel = uipanel(app.GridLayout3);
            app.GRLPanel.Title = 'GRL';
            app.GRLPanel.Visible = 'off';
            app.importButton_2 = uibutton(app.GRLPanel, 'push');
            app.importButton_2.ButtonPushedFcn = createCallbackFcn(app, @importButton_2Pushed, true);
            app.importButton_2.Tag = 'grl';
            app.importButton_2.Text = 'import';
            app.TextArea = uitextarea(app.GRLPanel);
            app.TextArea.Editable = 'off';
            app.GRLLabel = uilabel(app.GridLayout3);
            app.GRLLabel.Tooltip = {''};
            app.GRLLabel.Text = 'GRL';
            app.CheckBox_2 = uicheckbox(app.GridLayout3);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Tag = 'grl';
            app.CheckBox_2.Text = '';
            app.AUFLabel = uilabel(app.GridLayout3);
            app.AUFLabel.Tooltip = {''};
            app.AUFLabel.Text = 'AUF';
            app.CheckBox_3 = uicheckbox(app.GridLayout3);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Tag = 'grl';
            app.CheckBox_3.Text = '';
            app.nameEditFieldLabel = uilabel(app.GridLayout3);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.GridLayout3, 'text');
            app.nameEditField.Placeholder = '(lens)';
            app.thicknessEI0EditFieldLabel = uilabel(app.GridLayout3);
            app.thicknessEI0EditFieldLabel.Text = 'thickness @ EI0';
            app.thicknessEI0EditField = uieditfield(app.GridLayout3, 'numeric');
            app.thicknessEI0EditField.Limits = [0 Inf];
            app.thicknessEI0EditField.Tooltip = {'gp substrate + 0.5 * gp structure'};
            app.thicknessEI0EditField.Value = 1.524;
            app.refractiveindexEditFieldLabel = uilabel(app.GridLayout3);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.GridLayout3, 'numeric');
            app.refractiveindexEditField.Limits = [0 Inf];
            app.refractiveindexEditField.Value = 1.49;
            app.LRAEditFieldLabel = uilabel(app.GridLayout3);
            app.LRAEditFieldLabel.Text = 'LRA';
            app.LRAEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LRAEditField.Value = 12;
            app.segmentEditFieldLabel = uilabel(app.GridLayout3);
            app.segmentEditFieldLabel.Tooltip = {'分段數A (A+1為分段點)'};
            app.segmentEditFieldLabel.Text = 'segment ';
            app.segmentEditField = uieditfield(app.GridLayout3, 'numeric');
            app.segmentEditField.Limits = [0 Inf];
            app.segmentEditField.Value = 5;
            app.radiusEditFieldLabel = uilabel(app.GridLayout3);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.GridLayout3, 'numeric');
            app.radiusEditField.Limits = [0 Inf];
            app.radiusEditField.Tooltip = {'no GRL 時，以該值為主'};
            app.radiusEditField.Value = 1.49;
            app.pitchLabel = uilabel(app.GridLayout3);
            app.pitchLabel.Text = 'pitch';
            app.pitchEditField = uieditfield(app.GridLayout3, 'numeric');
            app.pitchEditField.Limits = [0 Inf];
            app.pitchEditField.Tooltip = {'no GRL 時，以該值為主'};
            app.pitchEditField.Value = 1.001;
            app.sizehorEditFieldLabel = uilabel(app.GridLayout3);
            app.sizehorEditFieldLabel.Text = 'size hor';
            app.sizehorEditField = uieditfield(app.GridLayout3, 'numeric');
            app.sizehorEditField.Value = 186.5;
            app.sizeverEditFieldLabel = uilabel(app.GridLayout3);
            app.sizeverEditFieldLabel.Text = 'size ver';
            app.sizeverEditField = uieditfield(app.GridLayout3, 'numeric');
            app.sizeverEditField.Value = 311.7;
            app.apertureEditFieldLabel = uilabel(app.GridLayout3);
            app.apertureEditFieldLabel.Text = 'aperture';
            app.apertureEditField = uieditfield(app.GridLayout3, 'numeric');
            app.apertureEditField.Limits = [0 Inf];
            app.apertureEditField.Tooltip = {'no GRL 時，以該值為主'};
            app.apertureEditField.Value = 1.001;
            app.lenstypeDropDownLabel = uilabel(app.GridLayout3);
            app.lenstypeDropDownLabel.Text = 'lens type';
            app.lenstypeDropDown = uidropdown(app.GridLayout3);
            app.lenstypeDropDown.Items = {'convex', 'concave'};
            app.lenstypeDropDown.Tooltip = {'convex: 凸透鏡'; 'concave: 凹透鏡'};
            app.lenstypeDropDown.Value = 'convex';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_lens(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end