classdef modify_cube < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        refractiveindexEditField       matlab.ui.control.NumericEditField
        refractiveindexEditFieldLabel  matlab.ui.control.Label
        thicknessEditField             matlab.ui.control.NumericEditField
        thicknessEditFieldLabel        matlab.ui.control.Label
        nameEditField                  matlab.ui.control.EditField
        nameEditFieldLabel             matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ModifyButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app % 主程式呼叫此程式
        temp_M Medium
        index % 修改的 medium 位置
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, index)
            % 在 startup function 新增輸入: 無法直接叫起
            % Store main app object (caller)
            app.CallingApp = caller;

            %% temp_Medium
            app.temp_M = app.CallingApp.M.list(index).copy; % 必須 copy, 否則會直接修改到
            app.index = index;

            %% 更新數據
            % index: 正在修改哪一個 Medium
            app.nameEditField.Value = app.temp_M.name;
            app.thicknessEditField.Value = app.temp_M.thickness;
            app.refractiveindexEditField.Value = app.temp_M.refractive_index;
            
        end

        % Button pushed function: ModifyButton
        function ModifyButtonPushed(app, event)
            % Call main app's public function
            update_from_modify(app.CallingApp,app.index,app.temp_M);
            
            % Enable Create button in main app
            app.CallingApp.CreateButton.Enable = "on";
            app.CallingApp.UITable.Enable = "on";
            
            % Delete the dialog box  
            delete(app) 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           disp("[info]: 取消")
            
           % Enable Create button in main app
           app.CallingApp.CreateButton.Enable = "on";
           app.CallingApp.UITable.Enable = "on";
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
           disp("[info]: 取消")
            
           % Enable Create button in main app
           app.CallingApp.CreateButton.Enable = "on";
           app.CallingApp.UITable.Enable = "on";
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Value changed function: nameEditField
        function nameEditFieldValueChanged(app, event)
            value = app.nameEditField.Value;
            app.temp_M.set_name(value)
        end

        % Value changed function: thicknessEditField
        function thicknessEditFieldValueChanged(app, event)
            value = app.thicknessEditField.Value;
            app.temp_M.set_t(value)
        end

        % Value changed function: refractiveindexEditField
        function refractiveindexEditFieldValueChanged(app, event)
            value = app.refractiveindexEditField.Value;
            app.temp_M.set_n(value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ModifyButton = uibutton(app.GridLayout, 'push');
            app.ModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonPushed, true);
            app.ModifyButton.Text = 'Modify';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.nameEditFieldLabel = uilabel(app.GridLayout);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.GridLayout, 'text');
            app.nameEditField.ValueChangedFcn = createCallbackFcn(app, @nameEditFieldValueChanged, true);
            app.nameEditField.Placeholder = '(cube)';
            app.thicknessEditFieldLabel = uilabel(app.GridLayout);
            app.thicknessEditFieldLabel.Text = 'thickness';
            app.thicknessEditField = uieditfield(app.GridLayout, 'numeric');
            app.thicknessEditField.Limits = [0 Inf];
            app.thicknessEditField.ValueChangedFcn = createCallbackFcn(app, @thicknessEditFieldValueChanged, true);
            app.refractiveindexEditFieldLabel = uilabel(app.GridLayout);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.GridLayout, 'numeric');
            app.refractiveindexEditField.Limits = [0 Inf];
            app.refractiveindexEditField.ValueChangedFcn = createCallbackFcn(app, @refractiveindexEditFieldValueChanged, true);
            app.refractiveindexEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modify_cube(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end