classdef modify_gp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        noGPPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        PBAEditField_2                 matlab.ui.control.NumericEditField
        PBAEditField_2Label            matlab.ui.control.Label
        PRAEditField                   matlab.ui.control.NumericEditField
        PRAEditFieldLabel              matlab.ui.control.Label
        reversedLabel                  matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        manualGPPanel                  matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        PBAlistEditField               matlab.ui.control.EditField
        PBAlistEditFieldLabel          matlab.ui.control.Label
        importButton                   matlab.ui.control.Button
        size_verEditField              matlab.ui.control.NumericEditField
        size_verEditFieldLabel         matlab.ui.control.Label
        size_horEditField              matlab.ui.control.NumericEditField
        size_horEditFieldLabel         matlab.ui.control.Label
        autoGPPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        WDGPEditField                  matlab.ui.control.NumericEditField
        WDGPEditFieldLabel             matlab.ui.control.Label
        PBAEditField                   matlab.ui.control.NumericEditField
        PBAEditFieldLabel              matlab.ui.control.Label
        listEditField                  matlab.ui.control.EditField
        listEditFieldLabel             matlab.ui.control.Label
        GPModeDropDown                 matlab.ui.control.DropDown
        GPModeDropDownLabel            matlab.ui.control.Label
        refractiveindexEditField       matlab.ui.control.NumericEditField
        refractiveindexEditFieldLabel  matlab.ui.control.Label
        thicknessEditField             matlab.ui.control.NumericEditField
        thicknessEditFieldLabel        matlab.ui.control.Label
        nameEditField                  matlab.ui.control.EditField
        nameEditFieldLabel             matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ModifyButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app % 主程式呼叫此程式
        temp_M Medium
        index % 修改的 medium 位置
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, index)
            % Store main app object (caller)
            app.CallingApp = caller;

            %% temp_Medium
            app.temp_M = app.CallingApp.M.list(index).copy; % 必須 copy, 否則會直接修改到
            app.index = index;

            %% 更新數據
            % index: 正在修改哪一個 Medium
            app.nameEditField.Value = app.temp_M.name;
            app.thicknessEditField.Value = app.temp_M.thickness;
            app.refractiveindexEditField.Value = app.temp_M.refractive_index;
            app.CheckBox.Value = app.temp_M.reversed;
            % GPMode-related
            switch app.temp_M.GPMode
                case 0 % noGP
                    app.GPModeDropDown.Value = "noGP";
                    app.noGPPanel.Visible = "on";
                    app.noGPPanel.Enable = "on";
                    app.autoGPPanel.Visible = "off";
                    app.autoGPPanel.Enable = "off";
                    app.manualGPPanel.Visible = "off";
                    app.manualGPPanel.Enable = "off";
                    app.PBAEditField_2.Value = app.temp_M.PBA_key;
                case 1 % autoGP
                    app.GPModeDropDown.Value = "AutoGP";
                    app.noGPPanel.Visible = "off";
                    app.noGPPanel.Enable = "off";
                    app.autoGPPanel.Visible = "on";
                    app.autoGPPanel.Enable = "on";
                    app.manualGPPanel.Visible = "off";
                    app.manualGPPanel.Enable = "off";
                    app.PBAEditField.Value = app.temp_M.autoGP.PBA_center;
                    app.WDGPEditField.Value = app.temp_M.autoGP.WDGP;
                    app.listEditField.Value = app.temp_M.autoGP.medium_list_text;
                case 2 % manualGP
                    app.GPModeDropDown.Value = "ManualGP";
                    app.noGPPanel.Visible = "off";
                    app.noGPPanel.Enable = "off";
                    app.autoGPPanel.Visible = "off";
                    app.autoGPPanel.Enable = "off";
                    app.manualGPPanel.Visible = "on";
                    app.manualGPPanel.Enable = "on";
                    app.PBAlistEditField.Value = app.temp_M.manualGP.PBA_list_text;
                    app.size_horEditField.Value = app.temp_M.size_hor;
                    app.size_verEditField.Value = app.temp_M.size_ver;
            end
            app.PRAEditField.Value = app.temp_M.PRA;
        end

        % Button pushed function: ModifyButton
        function ModifyButtonPushed(app, event)
            % 重新建立 GP
            % 不使用 temp_M
            switch app.GPModeDropDown.Value
                case "noGP"
                    try
                        gp = GradientPrism( PBA_key = app.PBAEditField_2.Value,...
                                        GPMode = 0,...  % 0: noGP, 1: autoGP, 2: manualGP
                                        PRA = app.PRAEditField.Value,...
                                        refractive_index = app.refractiveindexEditField.Value,...
                                        thickness = app.thicknessEditField.Value,...
                                        reversed = app.CheckBox.Value);
                    catch e
                        % 建立路徑資訊
                        temp_path = strings(length(e.stack),1);
                        for ii = 1:length(e.stack)
                            temp_path(ii) = "at: line("+ num2str(e.stack(ii).line) + ") " + e.stack(ii).name;
                        end
                        errordlg(["創建 noGP 失敗";...
                                  "identifier: " + e.identifier;...
                                  "message: " + e.message;...
                                  temp_path])
                        return % 離開此 function (create button function)
                    end
                case "AutoGP"
                    try
                        eval("list="+app.listEditField.Value);
                    catch
                        errordlg(["創建 autoGP 失敗";...
                                  "無法識別 list"])
                        return % 離開此 function (create button function)
                    end
                    try
                        autoGP = AutoGP(medium_list = list,...
                                        WDGP = app.WDGPEditField.Value,...
                                        medium_list_text = app.listEditField.Value);
                        gp = GradientPrism( PBA_key = app.PBAEditField.Value,...
                                        GPMode = 1,...  % 0: noGP, 1: autoGP, 2: manualGP
                                        PRA = app.PRAEditField.Value,...
                                        refractive_index = app.refractiveindexEditField.Value,...
                                        thickness = app.thicknessEditField.Value,...
                                        autoGP = autoGP,...
                                        reversed = app.CheckBox.Value);
                    catch e
                        % 建立路徑資訊
                        temp_path = strings(length(e.stack),1);
                        for ii = 1:length(e.stack)
                            temp_path(ii) = "at: line("+ num2str(e.stack(ii).line) + ") " + e.stack(ii).name;
                        end
                        errordlg(["創建 autoGP 失敗";...
                                  "identifier: " + e.identifier;...
                                  "message: " + e.message;...
                                  temp_path])
                        return % 離開此 function (create button function)
                    end
                case "ManualGP"
                    try
                        eval("PBAlist="+app.PBAlistEditField.Value);
                    catch
                        errordlg(["創建 manualGP 失敗";...
                                  "無法識別 list"])
                        return % 離開此 function (create button function)
                    end
                    try
                        manualGP = ManualGP(PBA_list_text=app.PBAlistEditField.Value);
                        gp = GradientPrism( ...
                                            PBA_key = PBAlist,...
                                            GPMode = 2,...  % 0: noGP, 1: autoGP, 2: manualGP
                                            PRA = app.PRAEditField.Value,...
                                            refractive_index = app.refractiveindexEditField.Value,...
                                            thickness = app.thicknessEditField.Value,...
                                            size_hor = app.size_horEditField.Value,...   % optional: only for manualGP
                                            size_ver = app.size_verEditField.Value,...   % optional: only for manualGP
                                            manualGP = manualGP,...
                                            reversed = app.CheckBox.Value);
                    catch e
                        % 建立路徑資訊
                        temp_path = strings(length(e.stack),1);
                        for ii = 1:length(e.stack)
                            temp_path(ii) = "at: line("+ num2str(e.stack(ii).line) + ") " + e.stack(ii).name;
                        end
                        errordlg(["創建 manualGP 失敗";...
                                  "identifier: " + e.identifier;...
                                  "message: " + e.message;...
                                  temp_path])
                        return % 離開此 function (create button function)
                    end
            end
            gp.set_name(app.nameEditField.Value);
            gp.set_order(app.temp_M.order);

            % Call main app's public function
            update_from_modify(app.CallingApp,app.index,gp);
            
            % Enable Create button in main app
            app.CallingApp.CreateButton.Enable = "on";
            app.CallingApp.UITable.Enable = "on";
            
            % Delete the dialog box  
            delete(app) 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           disp("[info]: 取消")
            
           % Enable Create button in main app
           app.CallingApp.CreateButton.Enable = "on";
           app.CallingApp.UITable.Enable = "on";
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
           disp("[info]: 取消")
           
           % Enable Create button in main app
           app.CallingApp.CreateButton.Enable = "on";
           app.CallingApp.UITable.Enable = "on";
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Value changed function: GPModeDropDown
        function GPModeDropDownValueChanged(app, event)
            value = app.GPModeDropDown.Value;

            switch value
                case "noGP"
                    app.noGPPanel.Visible = "on";
                    app.noGPPanel.Enable = "on";
                    app.autoGPPanel.Visible = "off";
                    app.autoGPPanel.Enable = "off";
                    app.manualGPPanel.Visible = "off";
                    app.manualGPPanel.Enable = "off";
                case "AutoGP"
                    app.noGPPanel.Visible = "off";
                    app.noGPPanel.Enable = "off";
                    app.autoGPPanel.Visible = "on";
                    app.autoGPPanel.Enable = "on";
                    app.manualGPPanel.Visible = "off";
                    app.manualGPPanel.Enable = "off";
                case "ManualGP"
                    app.noGPPanel.Visible = "off";
                    app.noGPPanel.Enable = "off";
                    app.autoGPPanel.Visible = "off";
                    app.autoGPPanel.Enable = "off";
                    app.manualGPPanel.Visible = "on";
                    app.manualGPPanel.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ModifyButton = uibutton(app.GridLayout, 'push');
            app.ModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonPushed, true);
            app.ModifyButton.Text = 'Modify';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.nameEditFieldLabel = uilabel(app.GridLayout);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.GridLayout, 'text');
            app.nameEditField.Placeholder = '(gp)';
            app.thicknessEditFieldLabel = uilabel(app.GridLayout);
            app.thicknessEditFieldLabel.Text = 'thickness';
            app.thicknessEditField = uieditfield(app.GridLayout, 'numeric');
            app.thicknessEditField.Limits = [0 Inf];
            app.thicknessEditField.Tooltip = {'gp substrate + 0.5 * gp structure'};
            app.refractiveindexEditFieldLabel = uilabel(app.GridLayout);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.GridLayout, 'numeric');
            app.refractiveindexEditField.Limits = [0 Inf];
            app.refractiveindexEditField.Value = 1;
            app.GPModeDropDownLabel = uilabel(app.GridLayout);
            app.GPModeDropDownLabel.Text = 'GP Mode';
            app.GPModeDropDown = uidropdown(app.GridLayout);
            app.GPModeDropDown.Items = {'noGP', 'AutoGP', 'ManualGP'};
            app.GPModeDropDown.ValueChangedFcn = createCallbackFcn(app, @GPModeDropDownValueChanged, true);
            app.GPModeDropDown.Value = 'noGP';
            app.autoGPPanel = uipanel(app.GridLayout);
            app.autoGPPanel.Enable = 'off';
            app.autoGPPanel.Title = 'autoGP';
            app.autoGPPanel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.autoGPPanel);
            app.listEditFieldLabel = uilabel(app.GridLayout2);
            app.listEditFieldLabel.Text = 'list';
            app.listEditField = uieditfield(app.GridLayout2, 'text');
            app.listEditField.Value = '{Cube(),[],Cube()}';
            app.PBAEditFieldLabel = uilabel(app.GridLayout2);
            app.PBAEditFieldLabel.Text = ' PBA';
            app.PBAEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PBAEditField.Value = 41.5;
            app.WDGPEditFieldLabel = uilabel(app.GridLayout2);
            app.WDGPEditFieldLabel.Text = 'WDGP';
            app.WDGPEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WDGPEditField.Value = 550;
            app.manualGPPanel = uipanel(app.GridLayout);
            app.manualGPPanel.Enable = 'off';
            app.manualGPPanel.Title = 'manualGP';
            app.manualGPPanel.Visible = 'off';
            app.GridLayout2_2 = uigridlayout(app.manualGPPanel);
            app.size_horEditFieldLabel = uilabel(app.GridLayout2_2);
            app.size_horEditFieldLabel.Text = 'size_hor';
            app.size_horEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.size_verEditFieldLabel = uilabel(app.GridLayout2_2);
            app.size_verEditFieldLabel.Text = 'size_ver';
            app.size_verEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.importButton = uibutton(app.GridLayout2_2, 'push');
            app.importButton.Text = 'import';
            app.PBAlistEditFieldLabel = uilabel(app.GridLayout2_2);
            app.PBAlistEditFieldLabel.Text = 'PBA list';
            app.PBAlistEditField = uieditfield(app.GridLayout2_2, 'text');
            app.PBAlistEditField.Value = '[50,41.5,25]';
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.reversedLabel = uilabel(app.GridLayout);
            app.reversedLabel.Tooltip = {'結構面是否為朝下'};
            app.reversedLabel.Text = 'reversed';
            app.PRAEditFieldLabel = uilabel(app.GridLayout);
            app.PRAEditFieldLabel.Text = 'PRA';
            app.PRAEditField = uieditfield(app.GridLayout, 'numeric');
            app.noGPPanel = uipanel(app.GridLayout);
            app.noGPPanel.Title = 'noGP';
            app.GridLayout3 = uigridlayout(app.noGPPanel);
            app.PBAEditField_2Label = uilabel(app.GridLayout3);
            app.PBAEditField_2Label.Text = 'PBA';
            app.PBAEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modify_gp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end