classdef setup_eye < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        mediumwitheyePanel             matlab.ui.container.Panel
        refractiveindexEditField       matlab.ui.control.NumericEditField
        refractiveindexEditFieldLabel  matlab.ui.control.Label
        thicknessEditField             matlab.ui.control.NumericEditField
        thicknessEditFieldLabel        matlab.ui.control.Label
        nameEditField                  matlab.ui.control.EditField
        nameEditFieldLabel             matlab.ui.control.Label
        STAEditField                   matlab.ui.control.NumericEditField
        STAEditFieldLabel              matlab.ui.control.Label
        IPDEditField                   matlab.ui.control.NumericEditField
        IPDEditFieldLabel              matlab.ui.control.Label
        PSEditField                    matlab.ui.control.NumericEditField
        PSEditFieldLabel               matlab.ui.control.Label
        HVAEditField                   matlab.ui.control.NumericEditField
        HVAEditFieldLabel              matlab.ui.control.Label
        eyemodeDropDown                matlab.ui.control.DropDown
        eyemodeDropDownLabel           matlab.ui.control.Label
        VVAEditField                   matlab.ui.control.NumericEditField
        VVAEditFieldLabel              matlab.ui.control.Label
        VDEditField                    matlab.ui.control.NumericEditField
        VDEditFieldLabel               matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        SetupButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main app % 主程式呼叫此程式
        temp_E      % 眼睛
        temp_M      % 介質 List
        temp_M_eye  % 含眼睛的介質
    end
    
    methods (Access = private)
        function update_thickenss(app)
            app.thicknessEditField.Value = app.VDEditField.Value * cosd(app.VVAEditField.Value);     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            % Store main app object (caller)
            app.CallingApp = caller;

            % 若設定過眼睛，套用其值
            % 第一次設定: 套用預設值
            app.temp_E = app.CallingApp.E.copy;
            app.temp_M = app.CallingApp.M;
            app.temp_M_eye = app.temp_M.list(arrayfun(@(x) isequal(x.tag,"eye"),app.temp_M.list));
            if ~isempty(app.temp_M_eye)
                if isequal(app.temp_E.mode,0)
                    app.eyemodeDropDown.Value = "middle";
                elseif isequal(app.temp_E.mode,-1)
                    app.eyemodeDropDown.Value = "left";
                elseif isequal(app.temp_E.mode,1)
                    app.eyemodeDropDown.Value = "right";
                elseif isequal(app.temp_E.mode,[-1 1])
                    app.eyemodeDropDown.Value = "both";
                end
                app.IPDEditField.Value = app.temp_E.IPD;
                app.VDEditField.Value = app.temp_E.VD;
                app.VVAEditField.Value = app.temp_E.VVA_ori;
                app.HVAEditField.Value = app.temp_E.HVA_ori;
                app.PSEditField.Value = app.temp_E.PS;
                app.STAEditField.Value = app.temp_E.STA;
                app.nameEditField.Value = app.temp_M_eye.name;
                app.thicknessEditField.Value = app.temp_M_eye.thickness;
                app.refractiveindexEditField.Value = app.temp_M_eye.refractive_index;
            else % 預設值設定
                app.thicknessEditField.Value = app.VDEditField.Value * cosd(app.VVAEditField.Value);
            end
            
            
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            % create eye
            switch app.eyemodeDropDown.Value
                case "both"
                    eye_option = [-1 1];
                case "left"
                    eye_option = -1;
                case "right"
                    eye_option = 1;
                case "middle"
                    eye_option = 0;
            end
            eye = Eye(mode=eye_option,...
                      IPD=app.IPDEditField.Value,...
                      VD=app.VDEditField.Value,...
                      VVA=app.VVAEditField.Value,...
                      HVA=app.HVAEditField.Value,...
                      PS=app.PSEditField.Value,...
                      STA=app.STAEditField.Value);
            
            thickness = eye.VD * cosd(eye.VVA);
            % create cube with eye
            created_cube = Cube(thickness=thickness,...
                refractive_index=app.refractiveindexEditField.Value);
            created_cube.set_name(app.nameEditField.Value);
            created_cube.set_tag("eye");
            
            % Call main app's public function
            update_from_eye(app.CallingApp,created_cube,eye);
            
            % Enable Create button in main app
            enable(app.CallingApp,"on")
            
            % Delete the dialog box  
            delete(app) 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           disp("[info]: 取消")
            
           % Enable Create button in main app
           enable(app.CallingApp,"on")
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
           disp("[info]: 取消")
            
           % Enable Create button in main app
           enable(app.CallingApp,"on")
                    
           % Delete the dialog box 
           delete(app) 
        end

        % Value changed function: VDEditField
        function VDEditFieldValueChanged(app, event)
            app.update_thickenss
        end

        % Value changed function: VVAEditField
        function VVAEditFieldValueChanged(app, event)
            app.update_thickenss            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.VDEditFieldLabel = uilabel(app.UIFigure);
            app.VDEditFieldLabel.Text = 'VD';
            app.VDEditField = uieditfield(app.UIFigure, 'numeric');
            app.VDEditField.Limits = [0 Inf];
            app.VDEditField.ValueChangedFcn = createCallbackFcn(app, @VDEditFieldValueChanged, true);
            app.VDEditField.Value = 500;
            app.VVAEditFieldLabel = uilabel(app.UIFigure);
            app.VVAEditFieldLabel.Text = 'VVA';
            app.VVAEditField = uieditfield(app.UIFigure, 'numeric');
            app.VVAEditField.Limits = [0 Inf];
            app.VVAEditField.ValueChangedFcn = createCallbackFcn(app, @VVAEditFieldValueChanged, true);
            app.VVAEditField.Value = 30;
            app.eyemodeDropDownLabel = uilabel(app.UIFigure);
            app.eyemodeDropDownLabel.Text = 'eye mode';
            app.eyemodeDropDown = uidropdown(app.UIFigure);
            app.eyemodeDropDown.Items = {'both', 'middle', 'left', 'right'};
            app.eyemodeDropDown.Value = 'both';
            app.HVAEditFieldLabel = uilabel(app.UIFigure);
            app.HVAEditFieldLabel.Text = 'HVA';
            app.HVAEditField = uieditfield(app.UIFigure, 'numeric');
            app.HVAEditField.Limits = [0 Inf];
            app.PSEditFieldLabel = uilabel(app.UIFigure);
            app.PSEditFieldLabel.Tooltip = {'半寬 (Radius)'};
            app.PSEditFieldLabel.Text = 'PS';
            app.PSEditField = uieditfield(app.UIFigure, 'numeric');
            app.PSEditField.Limits = [0 Inf];
            app.PSEditField.Tooltip = {'半寬 (Radius)'};
            app.PSEditField.Value = 15;
            app.IPDEditFieldLabel = uilabel(app.UIFigure);
            app.IPDEditFieldLabel.Text = 'IPD';
            app.IPDEditField = uieditfield(app.UIFigure, 'numeric');
            app.IPDEditField.Limits = [0 Inf];
            app.IPDEditField.Value = 60;
            app.STAEditFieldLabel = uilabel(app.UIFigure);
            app.STAEditFieldLabel.Tooltip = {'將在 setup 後重新計算 VVA HVA thickness'};
            app.STAEditFieldLabel.Text = 'STA';
            app.STAEditField = uieditfield(app.UIFigure, 'numeric');
            app.STAEditField.Limits = [0 Inf];
            app.STAEditField.Tooltip = {'將在 setup 後重新計算 VVA HVA thickness'};
            app.mediumwitheyePanel = uipanel(app.UIFigure);
            app.mediumwitheyePanel.Title = 'medium with eye';
            app.nameEditFieldLabel = uilabel(app.mediumwitheyePanel);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.mediumwitheyePanel, 'text');
            app.nameEditField.Placeholder = '(cube)';
            app.nameEditField.Value = 'air with eye';
            app.thicknessEditFieldLabel = uilabel(app.mediumwitheyePanel);
            app.thicknessEditFieldLabel.Tooltip = {'隨 VA 變動 (VD_z)'};
            app.thicknessEditFieldLabel.Text = 'thickness';
            app.thicknessEditField = uieditfield(app.mediumwitheyePanel, 'numeric');
            app.thicknessEditField.Limits = [0 Inf];
            app.thicknessEditField.Editable = 'off';
            app.thicknessEditField.Tooltip = {'隨 VA 變動 (VD_z)'};
            app.refractiveindexEditFieldLabel = uilabel(app.mediumwitheyePanel);
            app.refractiveindexEditFieldLabel.Text = 'refractive index';
            app.refractiveindexEditField = uieditfield(app.mediumwitheyePanel, 'numeric');
            app.refractiveindexEditField.Limits = [0 Inf];
            app.refractiveindexEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setup_eye(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end